classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GuiUIFigure                  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        LoadleftImageButton          matlab.ui.control.Button
        LoadrightImageButton         matlab.ui.control.Button
        runcomputeButton             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        DisplacementSlider           matlab.ui.control.Slider
        Displacement_valueLabel      matlab.ui.control.Label
        UIAxes2_2                    matlab.ui.control.UIAxes
        fullsizeButton               matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        UsedParameterTextAreaLabel   matlab.ui.control.Label
        UsedParameterTextArea        matlab.ui.control.TextArea
        loadparameterButton          matlab.ui.control.Button
        RecitifyTab                  matlab.ui.container.Tab
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        RecitifyButton               matlab.ui.control.Button
        windowlengthEditFieldLabel   matlab.ui.control.Label
        windowlengthEditField        matlab.ui.control.NumericEditField
        toleranceEditFieldLabel      matlab.ui.control.Label
        toleranceEditField           matlab.ui.control.NumericEditField
        min_corrEditFieldLabel       matlab.ui.control.Label
        min_corrEditField            matlab.ui.control.NumericEditField
        kEditFieldLabel              matlab.ui.control.Label
        kEditField                   matlab.ui.control.NumericEditField
        min_distEditFieldLabel       matlab.ui.control.Label
        min_distEditField            matlab.ui.control.NumericEditField
        NEditFieldLabel              matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        tauEditFieldLabel            matlab.ui.control.Label
        tauEditField                 matlab.ui.control.NumericEditField
        segmentlengthEditFieldLabel  matlab.ui.control.Label
        segmentlengthEditField       matlab.ui.control.NumericEditField
        TextArea_2                   matlab.ui.control.TextArea
        ShowHarrisButton             matlab.ui.control.Button
        ShowcorrelationsButton       matlab.ui.control.Button
        showramsacButton             matlab.ui.control.Button
        ComputeFButton               matlab.ui.control.Button
        TextArea_3                   matlab.ui.control.TextArea
        saveparameterButton          matlab.ui.control.Button
        DisperityMapTab              matlab.ui.container.Tab
        Button4                      matlab.ui.control.Button
        ScalingSlider                matlab.ui.control.Slider
        ScalingLabel                 matlab.ui.control.Label
        ReconstructionTab            matlab.ui.container.Tab
        TimeTab                      matlab.ui.container.Tab
    end

    
    properties (Access = public)
        %Property % Description
        figure_image_l;
        Image_L;
        figure_image_r;
        Image_R;
        figure_image_output;
        image_output;
        output_image;
        
        %Recitify
        figure_rec_l;
        rec_l;
        figure_rec_r;
        rec_r;
       
        
        %% Parameter
        
        param_rectify;
        param_disp;
        param_rectify_default;
        param_harris_default;
        param_corres_default;
        param_ramsac_default;
        param_disparity_default;
        
        % used parameter
        param_used;


        Scaling;
        p;
        halfBolcksize;
        disparityRange;
        
        %% points for algos
        
        
        %% state String
        str_state;
        str_rect_state;
        str_F;
        str_used_parameter;
        
    end

    methods (Access = private)
    
        function pull_rec_params(app)
            harris_var =         {'segment_length', app.segmentlengthEditField.Value ...
                                 ,'k',app.kEditField.Value ...
                                 ,'min_dist',app.min_distEditField.Value ...
                                 ,'N',app.NEditField.Value ...
                                 ,'tau',app.tauEditField.Value ...
                                 ,'do_plot',false ...
                                 };
            correspondence_var = {'window_length',app.windowlengthEditField.Value ...
                                 ,'min_corr',app.min_corrEditField.Value ...
                                 ,'do_plot',false
                                 };
    
            ramsac_var =         {'tolerance', app.toleranceEditField.Value};
    
            app.param_rectify =  {'harris_var', harris_var ...
                                 ,'correspondence_var', correspondence_var...
                                 ,'ramsac_var', ramsac_var...
                                 };


        end
        
        function pull_disp_params(app)
            app.param_disp =  app.param_disparity_default;
        end
        
        function [Error_string] = set_rec_params(app, parameter, name)
            Error_string = '';
            %% SET Parameter
            try
                app.param_disparity_default = parameter{6};
                app.param_rectify_default = parameter{8};
                app.param_harris_default = app.param_rectify_default{2};
                app.param_corres_default = app.param_rectify_default{4};
                app.param_ramsac_default = app.param_rectify_default{6};
                
                app.segmentlengthEditField.Value = app.param_harris_default{2};
                app.kEditField.Value = app.param_harris_default{4};
                app.min_distEditField.Value = app.param_harris_default{6};
                app.NEditField.Value = app.param_harris_default{8};
                app.tauEditField.Value = app.param_harris_default{4};
                app.windowlengthEditField.Value = app.param_corres_default{2};
                app.min_corrEditField.Value = app.param_corres_default{4};
                app.toleranceEditField.Value = app.param_ramsac_default{2};
                
                % set default as used
                app.param_used = parameter;
                app.str_used_parameter.Value={name};
                
                
            catch ME 
                Error_string = ME.message;
            end


        end
        
        function [image_gray_r, image_gray_l] = compute_image_grey(app)
            %% In Grauwertbilder konvertieren
            image_gray_r = mean(app.Image_R,3);
            image_gray_l = mean(app.Image_L,3);

            %% Gausfiltern
            image_gray_r = imgaussfilt( image_gray_r, 1 );
            image_gray_l = imgaussfilt( image_gray_l, 1 );
 
        end
        
        function [features_r, features_l] = compute_harris(app, image_gray_r, image_gray_l, print)
            addpath('rectification/');
            harris_params = app.param_rectify{2};
            harris_params{12} = print;
            
            app.str_rect_state.Value = {'Compute Harris images', ''};
            % compute Harris-features
            features_r = harris_detektor(image_gray_r,harris_params{:});
            features_l = harris_detektor(image_gray_l,harris_params{:});
            
            app.str_rect_state.Value = {'', ''};
        end
        
        function correspondence = compute_correlation(app, features_r, features_l, image_gray_r, image_gray_l, print)
            addpath('rectification/');
            correspondence_params = app.param_rectify{4};
            correspondence_params{6} = print;
            app.str_rect_state.Value = {'Compute Correlation images', ''};
            correspondence = punkt_korrespondenzen(image_gray_r,image_gray_l,...
                                            features_r,features_l, ...
                                            correspondence_params{:});
            app.str_rect_state.Value = {'', ''};
        end
        function correspondence_stable = compute_ramsac(app, correspondence, image_gray_r, image_gray_l, print)
            addpath('rectification/');
            ramsac_param = app.param_rectify{6};
            
            app.str_rect_state.Value = {'Compute ramsac', ''};
            correspondence_stable = F_ransac(correspondence, ramsac_param{:});
            if print
                show_correspondence(image_gray_r, image_gray_l,correspondence_stable) 
            end
            app.str_rect_state.Value = {'', ''};
        end
        function F = compute_F(app, correspondence_stable)
            addpath('rectification/');
            app.str_rect_state.Value = {'Compute F', ''};
            F = achtpunktalgorithmus(correspondence_stable);
            app.str_rect_state.Value = {'', ''};
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.figure_image_l = app.UIAxes;
            app.figure_image_r = app.UIAxes2;
            app.figure_image_output = app.UIAxes2_2;
            app.figure_rec_l = app.UIAxes_2;
            app.figure_rec_r = app.UIAxes2_3;


            %% READ IMAGE Left
            app.Image_L = imread('img/L2.JPG');
            I = imshow(app.Image_L ,'parent', app.figure_image_l);
            app.figure_image_l.XLim = [0 I.XData(2)];
            app.figure_image_l.YLim = [0 I.YData(2)];
            %set(app.UIAxes.XAxis, 'visible', 'off');
            %set(app.UIAxes.YAxis, 'visible', 'off');
            %set(app.UIAxes.YAxis, 'color', 'none');
            
            app.Image_R = imread('img/R2.JPG');
            I = imshow(app.Image_R ,'parent', app.figure_image_r);
            app.figure_image_r.XLim = [0 I.XData(2)];
            app.figure_image_r.YLim = [0 I.YData(2)];

            
            %% LOAD last output image
            char_p = num2str(app.p,'%01.1f\n');
            no_pic = false;
            try
                app.image_output = imread(strcat('results/p=',char_p,'.jpg'));
            catch ME
                no_pic = true;     
            end
            if no_pic 

            else
                I = imshow(app.image_output ,'parent',app.figure_image_output);
                app.figure_image_output.XLim = [0 I.XData(2)];
                app.figure_image_output.YLim = [0 I.YData(2)];
            end
            %% Load default values
            app.str_state = app.TextArea;
            app.str_state.Value = {'', ''};
            app.str_rect_state = app.TextArea_2;
            app.str_rect_state.Value = {'', ''};
            app.str_F = app.TextArea_3;
            app.str_F.Value={'','',''};
            app.str_used_parameter = app.UsedParameterTextArea;
            app.str_used_parameter.Value={'default_parameter.mat'};


            %% SET Parameter
            load 'parameter/default_parameter.mat' all_var;
            
            set_rec_params(app, all_var, 'default_parameter.mat');
            %{
            try
                app.param_disparity_default = all_var{6};
                app.param_rectify_default = all_var{8};
                app.param_harris_default = app.param_rectify_default{2};
                app.param_corres_default = app.param_rectify_default{4};
                app.param_ramsac_default = app.param_rectify_default{6};
                
                app.segmentlengthEditField.Value = app.param_harris_default{2};
                app.kEditField.Value = app.param_harris_default{4};
                app.min_distEditField.Value = app.param_harris_default{6};
                app.NEditField.Value = app.param_harris_default{8};
                app.tauEditField.Value = app.param_harris_default{4};
                app.windowlengthEditField.Value = app.param_corres_default{2};
                app.min_corrEditField.Value = app.param_corres_default{4};
                app.toleranceEditField.Value = app.param_ramsac_default{2};
                
                % set default as used
                app.param_used = all_var;
                app.str_used_parameter.Value={'default_parameter.mat'};
                
                
            catch ME 
                disp(ME.message);
            end
            %}
            
            
            app.Scaling = 0.1;
            app.p = 0.5;
            app.halfBolcksize = 2;
            app.disparityRange = 400;
            

        end

        % Button pushed function: LoadleftImageButton
        function LoadleftImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg','*.png'}, 'Select an image');
            app.Image_L = imread(fullfile(path,file));
            I = imshow(app.Image_L ,'parent',app.figure_image_l);
            app.figure_image_l.XLim = [0 I.XData(2)];
            app.figure_image_l.YLim = [0 I.YData(2)];
        end

        % Button pushed function: LoadrightImageButton
        function LoadrightImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg','*.png'}, 'Select an image');
            app.Image_R = imread(fullfile(path,file));
            I = imshow(app.Image_R ,'parent',app.figure_image_r);
            app.figure_image_r.XLim = [0 I.XData(2)];
            app.figure_image_r.YLim = [0 I.YData(2)];
        end

        % Value changed function: DisplacementSlider
        function DisplacementSliderValueChanged(app, event)
            app.p = app.DisplacementSlider.Value;
            %% LOAD last output image
            char_p = num2str(app.p,'%01.1f\n');
            no_pic = false;
            try
                app.image_output = imread(strcat('results/p=',char_p,'.jpg'));
            catch ME
                no_pic = true;     
            end
            if no_pic 
                % schow bad template pic
            else
                I = imshow(app.image_output ,'parent',app.figure_image_output);
                app.figure_image_output.XLim = [0 I.XData(2)];
                app.figure_image_output.YLim = [0 I.YData(2)];
            end
            
        end

        % Button pushed function: runcomputeButton
        function runcomputeButtonPushed(app, event)
           %% Execute free_viewpoint function
             all_var =          {'do_print', false ...
                                ,'displacement', app.p ...
                                ,'disparity_var', app.param_used{6} ...
                                ,'rectifiy_var', app.param_used{8}...
                                ,'gui_text_box', app.str_state ...
                                };
           
            try
                app.str_state.Value = {'computing state follows'};
                app.image_output = free_viewpoint(app.Image_R,  app.Image_L, ...
                                                all_var{:});
            catch inputError
                disp(strcat('ERROR: ', inputError.message))
                return;
            end
            
            I = imshow(app.image_output ,'parent',app.figure_image_output);
            app.figure_image_output.XLim = [0 I.XData(2)];
            app.figure_image_output.YLim = [0 I.YData(2)];
            app.str_state.Value = {'Idle'};

        end

        % Value changed function: ScalingSlider
        function ScalingSliderValueChanged(app, event)
            app.Scaling = app.ScalingSlider.Value;

        end

        % Button pushed function: RecitifyButton
        function RecitifyButtonPushed(app, event)
            try    
                app.str_rect_state.Value = {'Pull parameter', ''};
                app.pull_rec_params();
                
                addpath('rectification/');
                app.str_rect_state.Value = {'compute Recitificed images', ''};
                [app.rec_r, app.rec_l] = rectify_images(app.Image_R,  app.Image_L, app.param_rectify);
                
                I = imshow(app.rec_r ,'parent', app.figure_rec_r);
                app.figure_rec_r.XLim = [0 I.XData(2)];
                app.figure_rec_r.YLim = [0 I.YData(2)];
                
                I = imshow(app.rec_l ,'parent', app.figure_rec_l);
                app.figure_rec_l.XLim = [0 I.XData(2)];
                app.figure_rec_l.YLim = [0 I.YData(2)];
                app.str_rect_state.Value = {'', ''};
            catch ME
                app.str_rect_state.Value = {ME.message};
            end
            
        end

        % Button pushed function: ShowHarrisButton
        function ShowHarrisButtonPushed(app, event)
            try
                app.pull_rec_params();
                [image_gray_r, image_gray_l] = app.compute_image_grey();
                app.compute_harris(image_gray_r, image_gray_l, true);
            catch ME
                app.str_rect_state.Value = {ME.message};
            end
        end

        % Button pushed function: ShowcorrelationsButton
        function ShowcorrelationsButtonPushed(app, event)
            try    
                app.pull_rec_params();
                [image_gray_r, image_gray_l] = app.compute_image_grey();
                [features_r, features_l] = app.compute_harris(image_gray_r, image_gray_l, false);
                app.compute_correlation(features_r, features_l, image_gray_r, image_gray_l, true);
            catch ME
                app.str_rect_state.Value = {ME.message};
            end

        end

        % Button pushed function: showramsacButton
        function showramsacButtonPushed(app, event)
            try    
                app.pull_rec_params();
                [image_gray_r, image_gray_l] = app.compute_image_grey();
                [features_r, features_l] = app.compute_harris(image_gray_r, image_gray_l, false);
                correspondence = app.compute_correlation(features_r, features_l, image_gray_r, image_gray_l, false);
                compute_ramsac(app, correspondence, uint8(image_gray_r), uint8(image_gray_l), true);
            catch ME
                app.str_rect_state.Value = {ME.message};
            end

        end

        % Button pushed function: ComputeFButton
        function ComputeFButtonPushed(app, event)
            try
                app.pull_rec_params();
                [image_gray_r, image_gray_l] = app.compute_image_grey();
                [features_r, features_l] = app.compute_harris(image_gray_r, image_gray_l, false);
                correspondence = app.compute_correlation(features_r, features_l, image_gray_r, image_gray_l, false);
                correspondence_stable = compute_ramsac(app, correspondence, image_gray_r, image_gray_l, false);
                F = app.compute_F(correspondence_stable);
                app.str_F.Value = {char(mat2str(F(1:3),3)),char(mat2str(F(4:6),3)),char(mat2str(F(7:9),3))};
            catch ME
                app.str_rect_state.Value = {ME.message};
            end
            
        end

        % Button pushed function: saveparameterButton
        function saveparameterButtonPushed(app, event)
            app.pull_rec_params();
            all_var =          {'do_print', false ...
                                ,'displacement', app.p ...
                                ,'disparity_var', app.param_disp ...
                                ,'rectifiy_var', app.param_rectify...
                                ,'gui_text_box', app.str_state ...
                                };
            [file,path] = uiputfile('*.mat','Save parameter','parameter/*.mat');

            save(strcat(path,file), 'all_var');

        end

        % Button pushed function: loadparameterButton
        function loadparameterButtonPushed(app, event)
            
            [file,path] = uigetfile('*.mat','load parameter','parameter/*.mat');
            try
                load(strcat(path,file),'all_var');
                set_rec_params(app, all_var, char(file));
            catch ME
                app.str_state = ME.message;
            end
            app.str_state = 'load successful';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GuiUIFigure = uifigure;
            app.GuiUIFigure.Name = 'Gui';
            app.TabGroup = uitabgroup(app.GuiUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.LoadleftImageButton = uibutton(app.MainTab, 'push');
            app.LoadleftImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadleftImageButtonPushed, true);
            app.LoadleftImageButton.Text = 'Load left Image';
            app.LoadrightImageButton = uibutton(app.MainTab, 'push');
            app.LoadrightImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrightImageButtonPushed, true);
            app.LoadrightImageButton.Text = 'Load right Image';
            app.runcomputeButton = uibutton(app.MainTab, 'push');
            app.runcomputeButton.ButtonPushedFcn = createCallbackFcn(app, @runcomputeButtonPushed, true);
            app.runcomputeButton.Text = 'run compute';
            app.UIAxes = uiaxes(app.MainTab);
            app.UIAxes2 = uiaxes(app.MainTab);
            app.DisplacementSlider = uislider(app.MainTab);
            app.DisplacementSlider.Limits = [0 1];
            app.DisplacementSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DisplacementSlider.ValueChangedFcn = createCallbackFcn(app, @DisplacementSliderValueChanged, true);
            app.DisplacementSlider.MinorTicks = [];
            app.DisplacementSlider.Value = 0.5;
            app.Displacement_valueLabel = uilabel(app.MainTab);
            app.Displacement_valueLabel.Text = 'Displacement_value';
            app.UIAxes2_2 = uiaxes(app.MainTab);
            app.fullsizeButton = uibutton(app.MainTab, 'push');
            app.fullsizeButton.Text = 'fullsize';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'idle'; '-----'; ''};
            app.UsedParameterTextAreaLabel = uilabel(app.MainTab);
            app.UsedParameterTextAreaLabel.Text = 'Used Parameter';
            app.UsedParameterTextArea = uitextarea(app.MainTab);
            app.UsedParameterTextArea.Editable = 'off';
            app.UsedParameterTextArea.Enable = 'off';
            app.loadparameterButton = uibutton(app.MainTab, 'push');
            app.loadparameterButton.ButtonPushedFcn = createCallbackFcn(app, @loadparameterButtonPushed, true);
            app.loadparameterButton.Text = 'load parameter';
            app.RecitifyTab = uitab(app.TabGroup);
            app.RecitifyTab.Title = 'Recitify';
            app.UIAxes_2 = uiaxes(app.RecitifyTab);
            app.UIAxes2_3 = uiaxes(app.RecitifyTab);
            app.RecitifyButton = uibutton(app.RecitifyTab, 'push');
            app.RecitifyButton.ButtonPushedFcn = createCallbackFcn(app, @RecitifyButtonPushed, true);
            app.RecitifyButton.Text = 'Recitify';
            app.windowlengthEditFieldLabel = uilabel(app.RecitifyTab);
            app.windowlengthEditFieldLabel.Text = 'window length';
            app.windowlengthEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.windowlengthEditField.Limits = [0 50];
            app.windowlengthEditField.ValueDisplayFormat = '%.0f';
            app.toleranceEditFieldLabel = uilabel(app.RecitifyTab);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.toleranceEditField.Limits = [0 Inf];
            app.toleranceEditField.ValueDisplayFormat = '%.3f';
            app.min_corrEditFieldLabel = uilabel(app.RecitifyTab);
            app.min_corrEditFieldLabel.Text = 'min_corr';
            app.min_corrEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.min_corrEditField.Limits = [0 1];
            app.min_corrEditField.ValueDisplayFormat = '%.3f';
            app.kEditFieldLabel = uilabel(app.RecitifyTab);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.kEditField.Limits = [0 1];
            app.kEditField.ValueDisplayFormat = '%.3f';
            app.min_distEditFieldLabel = uilabel(app.RecitifyTab);
            app.min_distEditFieldLabel.Text = 'min_dist';
            app.min_distEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.min_distEditField.Limits = [0 100];
            app.min_distEditField.ValueDisplayFormat = '%.0f';
            app.NEditFieldLabel = uilabel(app.RecitifyTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.NEditField.Limits = [0 Inf];
            app.NEditField.ValueDisplayFormat = '%.0f';
            app.tauEditFieldLabel = uilabel(app.RecitifyTab);
            app.tauEditFieldLabel.Text = 'tau';
            app.tauEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.tauEditField.Limits = [0 1];
            app.tauEditField.ValueDisplayFormat = '%.5f';
            app.segmentlengthEditFieldLabel = uilabel(app.RecitifyTab);
            app.segmentlengthEditFieldLabel.Text = 'segment length';
            app.segmentlengthEditField = uieditfield(app.RecitifyTab, 'numeric');
            app.segmentlengthEditField.Limits = [0 Inf];
            app.segmentlengthEditField.ValueDisplayFormat = '%.0f';
            app.TextArea_2 = uitextarea(app.RecitifyTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Value = {'idle'; '-----'; ''};
            app.ShowHarrisButton = uibutton(app.RecitifyTab, 'push');
            app.ShowHarrisButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHarrisButtonPushed, true);
            app.ShowHarrisButton.Text = 'Show Harris';
            app.ShowcorrelationsButton = uibutton(app.RecitifyTab, 'push');
            app.ShowcorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowcorrelationsButtonPushed, true);
            app.ShowcorrelationsButton.Text = 'Show correlations';
            app.showramsacButton = uibutton(app.RecitifyTab, 'push');
            app.showramsacButton.ButtonPushedFcn = createCallbackFcn(app, @showramsacButtonPushed, true);
            app.showramsacButton.Text = 'show ramsac';
            app.ComputeFButton = uibutton(app.RecitifyTab, 'push');
            app.ComputeFButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeFButtonPushed, true);
            app.ComputeFButton.Text = 'Compute F';
            app.TextArea_3 = uitextarea(app.RecitifyTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.saveparameterButton = uibutton(app.RecitifyTab, 'push');
            app.saveparameterButton.ButtonPushedFcn = createCallbackFcn(app, @saveparameterButtonPushed, true);
            app.saveparameterButton.Text = 'save parameter';
            app.DisperityMapTab = uitab(app.TabGroup);
            app.DisperityMapTab.Title = 'DisperityMap';
            app.Button4 = uibutton(app.DisperityMapTab, 'push');
            app.Button4.Text = 'Button4';
            app.ScalingSlider = uislider(app.DisperityMapTab);
            app.ScalingSlider.Limits = [0.1 1];
            app.ScalingSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ScalingSlider.MajorTickLabels = {'0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.ScalingSlider.ValueChangedFcn = createCallbackFcn(app, @ScalingSliderValueChanged, true);
            app.ScalingSlider.Value = 1;
            app.ScalingLabel = uilabel(app.DisperityMapTab);
            app.ScalingLabel.Text = 'Scaling';
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GuiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GuiUIFigure)
        end
    end
end